classdef ProyectoMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RaicesTextArea        matlab.ui.control.TextArea
        RaicesLabel           matlab.ui.control.Label
        ymaxEditField         matlab.ui.control.NumericEditField
        ymaxEditFieldLabel    matlab.ui.control.Label
        xmaxEditField         matlab.ui.control.NumericEditField
        xmaxEditFieldLabel    matlab.ui.control.Label
        yminEditField         matlab.ui.control.NumericEditField
        yminEditFieldLabel    matlab.ui.control.Label
        xminEditField         matlab.ui.control.NumericEditField
        xminEditFieldLabel    matlab.ui.control.Label
        GraficarButton        matlab.ui.control.Button
        fxEditField           matlab.ui.control.EditField
        fxEditFieldLabel      matlab.ui.control.Label
        EscribelafuncinLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %polinomio como cadena de caracteres
            pchar = app.fxEditField.Value;
            %convertir el polinomio en variable simbolica
            psym = str2sym(pchar);
            %polinomio como vector
            pvec = sym2poly(psym);
            %raices
            proots = roots(pvec);
            app.RaicesTextArea.Value = string(proots);

            %graficar 
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;

            %evaluación del polinomio
            x       = linspace(xmin,xmax,100);
            y       = polyval(pvec,x);
            plot(app.UIAxes,x,y,'b-');

            %Limites
            if isinf(ymin)
                ymin = min(y);
            end
            if isinf(ymax)
                ymax = max(y);
            end
            app.UIAxes.XLim = [xmin,xmax];
            app.UIAxes.YLim = [ymin,ymax];
            
            %Grid
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Regresión Lineal')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.EscribelafuncinLabel = uilabel(app.UIFigure);
            app.EscribelafuncinLabel.Text = 'Escribe la función:';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)=';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.Value = '(x+0.2)*(x-0.2)*(x-0.6)';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.Value = -1;
            app.yminEditFieldLabel = uilabel(app.UIFigure);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.Value = -Inf;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.Value = 1;
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.Value = Inf;
            app.RaicesLabel = uilabel(app.UIFigure);
            app.RaicesLabel.Text = 'Raices:';
            app.RaicesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end